classdef App_LC_MSMS_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LoadMenu          matlab.ui.container.Menu
        LC_MSMS_dataMenu  matlab.ui.container.Menu
        RawDataMenu       matlab.ui.container.Menu
        Switch            matlab.ui.control.Switch
        UITable           matlab.ui.control.Table
        UIAxes_5          matlab.ui.control.UIAxes
        UIAxes_4          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        path
        file
        ionMode
        pol
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: RawDataMenu
        function RawDataMenuSelected(app, event)
            global LC_MSMS_data
            load_LC_MSMS_data
        end

        % Menu selected function: LC_MSMS_dataMenu
        function LC_MSMS_dataMenuSelected(app, event)
            global LC_MSMS_data            
            [app.file, app.path] = uigetfile('.mat', 'Select Matlab File with impirted LC_MSMS_data');
            load([app.path, app.file])

            % preset
            app.ionMode = "neg";
            app.Switch.Value = 'neg';
            app.pol = 2;
            
            set(app.UITable, 'data', [table(LC_MSMS_data(app.pol).SampleInf.MetStrainPol,...
                LC_MSMS_data(app.pol).foldChange,...
                {LC_MSMS_data(app.pol).QC.IntPassed}', {LC_MSMS_data(app.pol).QC.SidePeaksPassed}',...
                'VariableNames',{'Met-Strain-Pol','fold-change' ,'QC_int', 'QC_SidePeaks'})])

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global LC_MSMS_data
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)

            value = app.Switch.Value;
            if(strcmp(value,'pos'))
                app.ionMode = "pos";
                app.pol = 1;
            else
                app.ionMode = "neg";
                app.pol = 2;
            end
            set(app.UITable, 'data', [table(LC_MSMS_data(app.pol).SampleInf.MetStrainPol,...
                LC_MSMS_data(app.pol).foldChange,...
                {LC_MSMS_data(app.pol).QC.IntPassed}', {LC_MSMS_data(app.pol).QC.SidePeaksPassed}',...
                'VariableNames',{'Met-Strain-Pol','fold-change' ,'QC_int', 'QC_SidePeaks'})])
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            idx_selection = event.Indices;
            global LC_MSMS_data
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)

            % indices(1) zeigt ausgewählte reihe an
            MakePlot_Median(LC_MSMS_data, app, idx_selection)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.Text = 'Load';
            app.LC_MSMS_dataMenu = uimenu(app.LoadMenu);
            app.LC_MSMS_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @LC_MSMS_dataMenuSelected, true);
            app.LC_MSMS_dataMenu.Text = 'LC_MSMS_data';
            app.RawDataMenu = uimenu(app.LoadMenu);
            app.RawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDataMenuSelected, true);
            app.RawDataMenu.Text = 'RawData';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Met-Strain-Pol'; 'fold-change'; 'QC_int_passed'; 'QC_MS1_passed'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'pos', 'neg'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'pos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_LC_MSMS_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end